classdef audiodiary < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AudioDiaryLabel           matlab.ui.control.Label
        StartButton_2             matlab.ui.control.Button
        UpdateFilePanel           matlab.ui.container.Panel
        StopButton_2              matlab.ui.control.Button
        MenuPanel                 matlab.ui.container.Panel
        EmotionRecognitionButton  matlab.ui.control.Button
        ControlPanel              matlab.ui.container.Panel
        ReadTextButton            matlab.ui.control.Button
        PlayVoiceButton           matlab.ui.control.Button
        DeleteButton              matlab.ui.control.Button
        RecordPanel               matlab.ui.container.Panel
        StopButton                matlab.ui.control.Button
        StartButton               matlab.ui.control.Button
        FeedbackTextArea          matlab.ui.control.TextArea
        FeedbackTextAreaLabel     matlab.ui.control.Label
        PlotButton                matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        % All of these variables are used as global ones, to pass values 
        % on other functions.
        recObj
        voice 
        t
        recordObj
        wav1
        Fs1
        fileName
        monthFile
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Find current month and use it later to name a folder
            format shortg
            c = clock;
            monthNames = {'January','Febuary','March','April','May','June','July','August','September','October','November','December'};
            monthNumber = c(2);
            app.monthFile = monthNames(monthNumber);
            app.monthFile = cell2mat(app.monthFile);

            % Check if records folder exists. If not create it, and then
            % the same process for current month subfolder. If folder
            % records exists, check for current month subfolder and create
            % it or not.
            if not(isfolder('records'))
                mkdir('records')
                if not(isfolder(sprintf('records/%s', app.monthFile)))
                    mkdir(sprintf('records/%s', app.monthFile))
                end
            else
                if not(isfolder(sprintf('records/%s', app.monthFile)))
                    mkdir(sprintf('records/%s', app.monthFile))
                end
            end

            % Adding all necessary paths to MATLAB path, to access files
            addpath('records')
            addpath(sprintf('records/%s', app.monthFile))
            addpath("Speech-to-Text\")

            % Needed variables for audio record
            Fs = 44100;
            nBits = 16;
            nChannels = 1;
            app.recObj = audiorecorder(Fs, nBits, nChannels);

            % Start recording
            app.FeedbackTextArea.Value = 'Recording...'
            record(app.recObj);

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Stop recording
            stop(app.recObj);

            % Name file according to datetime, YYYY.MM.DD--HH-MM-SS
            format shortg;
            c = clock;
            c = fix(c);
            str = num2str(c);
            str = strsplit(str);
            str = strjoin(str,{'.','.','--','-','-'});
            
            % Save audio data of recording to .wav file
            Fs = 44100;
            app.voice = getaudiodata(app.recObj);
            audiowrite(sprintf('records/%s/%s.wav', app.monthFile, str), app.voice, Fs);

            % Speech-to-Text conversion
            trans = wav2vec(app.voice, Fs);
            app.FeedbackTextArea.Value = trans;

            % Open or create a .txt file and write the converted voice text
            fileID = fopen(sprintf('records/%s/%s.txt', app.monthFile, str), 'at+');
            fprintf(fileID, '%s\n', trans);
            fclose(fileID);
                        
            dt = 1/Fs; % second
            L = length(app.voice); % size of voice
            app.t = (0:L-1)*dt; % second

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            scatter(app.UIAxes, app.t, app.voice)

        end

        % Callback function
        function TextAreaValueChanging(app, event)

        end

        % Callback function
        function EditFieldValueChanged(app, event)

        end

        % Callback function
        function EditFieldValueChanged2(app, event)

        end

        % Button pushed function: PlayVoiceButton
        function PlayVoiceButtonPushed(app, event)
            % Select and play a audio file
            file = uigetfile('*.wav');
            [y, Fs] = audioread(file);
            sound(y, Fs);

        end

        % Button pushed function: ReadTextButton
        function ReadTextButtonPushed(app, event)
            % Select and open a text file
            file = uigetfile('*.txt');
            fileID = fopen(file,'r');
            formatSpec = '%20c';
            A = fscanf(fileID,formatSpec);
            app.FeedbackTextArea.Value = A;
            fclose(fileID);

        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            % Find current month and use it later to add the current month
            % folder to MATLAB path
            format shortg
            c = clock;
            monthNames = {'January','Febuary','March','April','May','June','July','August','September','October','November','December'};
            monthNumber = c(2);
            app.monthFile = monthNames(monthNumber);
            app.monthFile = cell2mat(app.monthFile);
            
            % Adding all necessary paths to MATLAB path, to access files
            addpath('records')
            addpath(sprintf('records/%s', app.monthFile))

            filerec = uigetfile({'*.wav';'*.txt;'});
            delete(sprintf('records/%s/%s', app.monthFile ,filerec))

        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            % Find current month and use it later to add the current month
            % folder to MATLAB path
            format shortg
            c = clock;
            monthNames = {'January','Febuary','March','April','May','June','July','August','September','October','November','December'};
            monthNumber = c(2);
            app.monthFile = monthNames(monthNumber);
            app.monthFile = cell2mat(app.monthFile);
            
            % Adding all necessary paths to MATLAB path, to access files
            addpath('records')
            addpath(sprintf('records/%s', app.monthFile))

            % Select a audio file to record more
            file = uigetfile('*.wav');
            [~, app.fileName, ~] = fileparts(file);
            [app.wav1, app.Fs1] = audioread(file);

            % Needed variables for audio record
            Fs = 44100;
            nBits = 16;
            nChannels = 1;
            app.recordObj = audiorecorder(Fs, nBits, nChannels);

            % Start recording
            app.FeedbackTextArea.Value = 'Recording...'
            record(app.recordObj);

        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            % Stop recording
            stop(app.recordObj);
            
            % Save audio data of recording to .wav file
            Fs = 44100;
            recVoice = getaudiodata(app.recordObj);
            audiowrite('recVoice.wav', recVoice, Fs);
            [wav2, Fs2] = audioread('recVoice.wav');

            % Check both audio files' frequency, and resample them to the
            % highest of both
            maxFs = max(app.Fs1, Fs2);
            rs1 = resample(app.wav1, maxFs, app.Fs1);
            rs2 = resample(wav2, maxFs, Fs2);

            % Add the current recording at the end of chosen audio file
            % with a small pause in between
            rs = [rs1;zeros(1);rs2];
            
            % Delete chosen audio file for memory space, no more needed as
            % the new one contains it
            delete(sprintf('record/%s/%s.wav', app.monthFile, app.fileName))
            % Save new audio file
            audiowrite(sprintf('records/%s/%s.wav', app.monthFile, app.fileName), rs, maxFs);

            % Speech-to-Text conversion
            trans = wav2vec(rs, Fs);
            app.FeedbackTextArea.Value = trans;

            %Open or create a .txt file and write the converted voice text
            fileID = fopen(sprintf('records/%s/%s.txt', app.monthFile, app.fileName), 'wt+');
            fprintf(fileID, '%s\n', trans);
            fclose(fileID);

            % Delete current recording for memory space, no more needed
            delete("recVoice.wav");

        end

        % Button pushed function: EmotionRecognitionButton
        function EmotionRecognitionButtonPushed(app, event)
            % Adding all necessary paths to MATLAB path, to access files
            addpath('Emotion-Recognition')
            addpath('Emotion-Recognition/Test')
            if (isfolder('records'))
                addpath('records')
                addpath(sprintf('records/%s', app.monthFile))
            end
            % Change path to acess .m files
            cd Emotion-Recognition\


            emotions = ["Anger" "Sadness" "Elation" "Happiness"];
            nNeighbors = 3;
            frameDuration = 20;
            frameShift = 10;
            preemphasis = 0.97;
            nFilterbankChannels = 20;
            nCepstralCoefficients = 12;
            cepstralSineLifter = 22;
            lowerFrequency = 300;
            upperFrequency = 3700;
            data = double.empty;
            classes = cell(0);
            
            % Feature Extraction

            for i = 1 : size(emotions, 2)
                toLearn = emotions(i);
               
                % Check number of files in each emotion folder
                filePattern = fullfile(toLearn, '*.wav');
                nFiles = dir(filePattern);
                
                % Iterate through each file, read it and then extract
                % features using mfcc
                for j = 1 : length(nFiles)
                    [speech, fs] = audioread([char(toLearn) '/' char(lower(toLearn)) int2str(j) '.wav']);
                    
                    analysis = mfcc(speech, fs, frameDuration, frameShift, preemphasis, @hamming,...
                    [lowerFrequency upperFrequency], nFilterbankChannels, nCepstralCoefficients + 1, cepstralSineLifter);
            
                    data = [data; analysis'];
                    classes = [classes; repmat(cellstr(toLearn), size(analysis, 2), 1)];
                end
            end
            
            % Classification

            % Multi-SVM Classification
            %fprintf('Preparing Multi-SVM Classifier.\n');
            %classificationModel = fitcecoc(data, classes);
            %fprintf('Multi-SVM Classifier Ready\n');

            % KNN Classification
            %fprintf('Preparing KNN Classifier\n');
            classificationModel = fitcknn(data, classes, 'Standardize', 1);
            %fprintf('KNN Classifier Ready\n');
            
            % Choose a file for classification
            classifyThis = uigetfile('*.wav');
            [speech, speechFs] = audioread(classifyThis);
            if(size(speech, 2) > 1)
                speech = speech(:, 1);
            end
            analysis = mfcc(speech, speechFs, frameDuration, frameShift, preemphasis, @hamming, ...
                [lowerFrequency upperFrequency], nFilterbankChannels, nCepstralCoefficients + 1, cepstralSineLifter);
            x = analysis';
            
            % labels = predict(Multi-SVM, x);
            labels = predict(classificationModel, x);
            
            results = zeros(size(emotions, 2), 1);
            
            for i = 1 : size(labels, 1)
                index = find(strcmp(labels(i), emotions));
                results(index) = results(index) + 1;
            end
            
            results = results ./ size(labels, 1);
            
            [maxWell, maxPlace] = max(results);
            
            fprintf('Classifying as %s with a confidence of %f\n', emotions(maxPlace), maxWell);
            app.FeedbackTextArea.Value = emotions(maxPlace);
            
            % Show MFCC diagram in a pop up window
            figure
            imagesc(analysis);
            xlabel('sample');
            ylabel('log energy');
            title('Mfcc');
               
            % Return to Audio Diary folder
            cd ../

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Audio')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FeedbackTextAreaLabel = uilabel(app.UIFigure);
            app.FeedbackTextAreaLabel.Text = 'Feedback';
            app.FeedbackTextArea = uitextarea(app.UIFigure);
            app.MenuPanel = uipanel(app.UIFigure);
            app.MenuPanel.Title = 'Menu';
            app.RecordPanel = uipanel(app.MenuPanel);
            app.RecordPanel.Title = 'Record';
            app.StartButton = uibutton(app.RecordPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.RecordPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ControlPanel = uipanel(app.MenuPanel);
            app.ControlPanel.Title = 'Control';
            app.DeleteButton = uibutton(app.ControlPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.PlayVoiceButton = uibutton(app.ControlPanel, 'push');
            app.PlayVoiceButton.ButtonPushedFcn = createCallbackFcn(app, @PlayVoiceButtonPushed, true);
            app.PlayVoiceButton.Text = 'Play Voice';
            app.ReadTextButton = uibutton(app.ControlPanel, 'push');
            app.ReadTextButton.ButtonPushedFcn = createCallbackFcn(app, @ReadTextButtonPushed, true);
            app.ReadTextButton.Text = 'Read Text';
            app.EmotionRecognitionButton = uibutton(app.MenuPanel, 'push');
            app.EmotionRecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @EmotionRecognitionButtonPushed, true);
            app.EmotionRecognitionButton.Text = 'Emotion Recognition';
            app.UpdateFilePanel = uipanel(app.UIFigure);
            app.UpdateFilePanel.Title = 'Update File';
            app.StopButton_2 = uibutton(app.UpdateFilePanel, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.StartButton_2 = uibutton(app.UIFigure, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.AudioDiaryLabel = uilabel(app.UIFigure);
            app.AudioDiaryLabel.Text = 'Audio Diary';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audiodiary

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end